classdef main_ISS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RegistertilesButton            matlab.ui.control.Button
        FindspotsButton                matlab.ui.control.Button
        CallspotsButton                matlab.ui.control.Button
        MakeDAPIimageButton            matlab.ui.control.Button
        DapiChannelEditFieldLabel      matlab.ui.control.Label
        DapiChannelEditField           matlab.ui.control.NumericEditField
        DapiRoundEditFieldLabel        matlab.ui.control.Label
        DapiRoundEditField             matlab.ui.control.NumericEditField
        RoundstoUseListBoxLabel        matlab.ui.control.Label
        RoundstoUseListBox             matlab.ui.control.ListBox
        ChannelstoUseListBoxLabel      matlab.ui.control.Label
        ChannelstoUseListBox           matlab.ui.control.ListBox
        MaininitialparametersPanel     matlab.ui.container.Panel
        AnchorChannelEditField_2Label  matlab.ui.control.Label
        AnchorChannelEditField         matlab.ui.control.NumericEditField
        InputDirectoryEditFieldLabel   matlab.ui.control.Label
        InputDirectoryEditField        matlab.ui.control.EditField
        SliceprefixEditField_2Label    matlab.ui.control.Label
        SliceprefixEditField           matlab.ui.control.EditField
        AnchorRoundEditField_2Label    matlab.ui.control.Label
        AnchorRoundEditField           matlab.ui.control.NumericEditField
        GadChannelEditField_2Label     matlab.ui.control.Label
        GadChannelEditField            matlab.ui.control.NumericEditField
        GadRoundEditField_2Label       matlab.ui.control.Label
        GadRoundEditField              matlab.ui.control.NumericEditField
        GcampChannelEditField_2Label   matlab.ui.control.Label
        GcampChannelEditField          matlab.ui.control.NumericEditField
        GcampRoundEditField_2Label     matlab.ui.control.Label
        GcampRoundEditField            matlab.ui.control.NumericEditField
        InitialShiftChannelEditField_2Label  matlab.ui.control.Label
        InitialShiftChannelEditField   matlab.ui.control.NumericEditField
        RawFileExtensionEditField_3Label  matlab.ui.control.Label
        RawFileExtensionEditField      matlab.ui.control.EditField
        RoundsuffixEditField_2Label    matlab.ui.control.Label
        RoundsuffixEditField           matlab.ui.control.EditField
        NumberofcolorchannelsEditField_2Label  matlab.ui.control.Label
        nBPEditField                   matlab.ui.control.NumericEditField
        nRoundsEditFieldLabel          matlab.ui.control.Label
        nRoundsEditField               matlab.ui.control.NumericEditField
        nExtraRoundsEditFieldLabel     matlab.ui.control.Label
        nExtraRoundsEditField          matlab.ui.control.NumericEditField
        OutputDirectoryEditFieldLabel  matlab.ui.control.Label
        OutputDirectoryEditField       matlab.ui.control.EditField
        ExtractandfilterButton_2       matlab.ui.control.Button
        RunAllButton_2                 matlab.ui.control.Button
        ParametersthatcanbechangedafterextractandfilterLabel  matlab.ui.control.Label
        IgnoreFirstChannelEditFieldLabel  matlab.ui.control.Label
        IgnoreFirstChannelEditField    matlab.ui.control.NumericEditField
        TilestoUseListBoxLabel         matlab.ui.control.Label
        TilestoUseListBox              matlab.ui.control.ListBox
        LOADButton                     matlab.ui.control.Button
        SteptoloadListBoxLabel         matlab.ui.control.Label
        SteptoloadListBox              matlab.ui.control.ListBox
        VisualizeselectedtilesButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        o % main iss object
    end
    
    methods (Access = private)
        
        function L = listItems(app, N, suff)
            L = {}; 
            for i=1:N
                L{i} = [suff,num2str(i)];
            end
        end
        function L = setItems(app, N, suff)
            L = {}; 
            for i=1:length(N)
                L{i} = [suff,num2str(N(i))];
            end
        end
        function o = loadISSfile(app,step)
            OutDir = app.OutputDirectoryEditField.Value;
            SliceNb = app.SliceprefixEditField.Value;
            OutputDirectory = fullfile(OutDir,SliceNb,'\output');
            load(fullfile(OutputDirectory, ['o',step]));
        end
        
        function plotTiles(app)
            o = app.o;
            BigAnchorFile = fullfile(o.OutputDirectory, 'anchor_image.tif');
            BigAnchorIm = imread(BigAnchorFile);
           
            
            TileList = app.TilestoUseListBox.Value;
            TileList = cellfun(@(x) str2num(x),TileList);
            
             
            o.EmptyTiles(:) = 1 ;
            o.EmptyTiles(TileList) = 0;
             NonemptyTiles = find(~o.EmptyTiles)';
            %Plot stitched anchor image
            SquareX1 = [0, 0, o.TileSz];
            SquareY1 = [o.TileSz, 0, 0];
            SquareX2 = [o.TileSz, o.TileSz, 0];
            SquareY2 = [0, o.TileSz, o.TileSz];
            figure(53277);
            set(gcf,'Position',[990,187,1042,1032])
            imagesc(imadjust(BigAnchorIm-o.TilePixelValueShift));
            hold on
            for t=NonemptyTiles
                MyOrigin = o.TileOrigin(t,:,o.ReferenceRound);
                plot(SquareX1 + MyOrigin(2), SquareY1 + MyOrigin(1),...
                    '--', 'Color', 'w');
                plot(SquareX2 + MyOrigin(2), SquareY2 + MyOrigin(1),...
                    ':', 'Color', 'w');
                text(MyOrigin(2)+max(SquareX1/2), MyOrigin(1)+max(SquareX1/2),...
                    sprintf('Tile %d', t), 'color', 'w','FontSize',18);
            end
            title('Stitched Anchor Image');
            daspect([1 1 1])
            hold off

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.nBPEditField.Value;
            L = listItems(app,value, '');
             app.ChannelstoUseListBox.Items = L;
             app.ChannelstoUseListBox.Value = L;
            value = app.nRoundsEditField.Value;
             L = listItems(app,value, '');
             app.RoundstoUseListBox.Items = L;
             app.RoundstoUseListBox.Value = L;
        end

        % Button pushed function: ExtractandfilterButton_2
        function ExtractandfilterButton_2Pushed(app, event)
            app = GUI_extractANDfilter(app);
        end

        % Button pushed function: RegistertilesButton
        function RegistertilesButtonPushed(app, event)
            app = GUI_register(app,app.o);
        end

        % Value changed function: nBPEditField
        function nBPEditFieldValueChanged(app, event)
            value = app.nBPEditField.Value;

        end

        % Value changed function: nRoundsEditField
        function nRoundsEditFieldValueChanged(app, event)
            value = app.nRoundsEditField.Value;
            
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            value = app.SteptoloadListBox.Value;
            o = loadISSfile(app,value);
            [nY, nX] = size(o.EmptyTiles);
            nTiles = nY*nX;
            L = listItems(app,nTiles, '');
            app.TilestoUseListBox.Items = L;
            
            NonemptyTiles = find(~o.EmptyTiles)';
            L = setItems(app, NonemptyTiles, '');
            app.TilestoUseListBox.Value = L;
            app.o=o;
        end

        % Button pushed function: FindspotsButton
        function FindspotsButtonPushed(app, event)
            app = GUI_find_spots(app,app.o);
        end

        % Button pushed function: CallspotsButton
        function CallspotsButtonPushed(app, event)
            app = GUI_call_spots(app,app.o);
        end

        % Button pushed function: VisualizeselectedtilesButton
        function VisualizeselectedtilesButtonPushed(app, event)
            plotTiles(app);
        end

        % Button pushed function: RunAllButton_2
        function RunAllButton_2Pushed(app, event)
            GUI_extractANDfilter(app);
            GUI_register(app,app.o);
            GUI_find_spots(app,app.o);
            GUI_call_spots(app,app.o);
        end

        % Value changed function: TilestoUseListBox
        function TilestoUseListBoxValueChanged(app, event)
            TileList = app.TilestoUseListBox.Value;
            TileList = cellfun(@(x) str2num(x),TileList);
            
            app.o.EmptyTiles(:) = 1 ;
            app.o.EmptyTiles(TileList) = 0;
        end

        % Value changed function: RoundstoUseListBox
        function RoundstoUseListBoxValueChanged(app, event)
            value = app.RoundstoUseListBox.Value;
             RoundList = app.RoundstoUseListBox.Value;
            RoundList = cellfun(@(x) str2num(x),RoundList);
            
            app.o.UseRounds = RoundList;
        end

        % Value changed function: ChannelstoUseListBox
        function ChannelstoUseListBoxValueChanged(app, event)
             ChannelList = app.ChannelstoUseListBox.Value;
            ChannelList = cellfun(@(x) str2num(x),ChannelList);
            
            app.o.UseRounds = ChannelList;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RegistertilesButton = uibutton(app.UIFigure, 'push');
            app.RegistertilesButton.ButtonPushedFcn = createCallbackFcn(app, @RegistertilesButtonPushed, true);
            app.RegistertilesButton.Text = 'Register tiles';
            app.FindspotsButton = uibutton(app.UIFigure, 'push');
            app.FindspotsButton.ButtonPushedFcn = createCallbackFcn(app, @FindspotsButtonPushed, true);
            app.FindspotsButton.Text = 'Find spots';
            app.CallspotsButton = uibutton(app.UIFigure, 'push');
            app.CallspotsButton.ButtonPushedFcn = createCallbackFcn(app, @CallspotsButtonPushed, true);
            app.CallspotsButton.Text = 'Call spots';
            app.MakeDAPIimageButton = uibutton(app.UIFigure, 'push');
            app.MakeDAPIimageButton.Text = 'MakeDAPIimage';
            app.DapiChannelEditFieldLabel = uilabel(app.UIFigure);
            app.DapiChannelEditFieldLabel.Text = 'DapiChannel';
            app.DapiChannelEditField = uieditfield(app.UIFigure, 'numeric');
            app.DapiChannelEditField.Value = 1;
            app.DapiRoundEditFieldLabel = uilabel(app.UIFigure);
            app.DapiRoundEditFieldLabel.Text = 'DapiRound';
            app.DapiRoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.DapiRoundEditField.Value = 8;
            app.RoundstoUseListBoxLabel = uilabel(app.UIFigure);
            app.RoundstoUseListBoxLabel.Text = 'Rounds to Use';
            app.RoundstoUseListBox = uilistbox(app.UIFigure);
            app.RoundstoUseListBox.Items = {'Round 1', 'Round 2', 'Round 3', 'Round 4'};
            app.RoundstoUseListBox.Multiselect = 'on';
            app.RoundstoUseListBox.ValueChangedFcn = createCallbackFcn(app, @RoundstoUseListBoxValueChanged, true);
            app.RoundstoUseListBox.Value = {'Round 1'};
            app.ChannelstoUseListBoxLabel = uilabel(app.UIFigure);
            app.ChannelstoUseListBoxLabel.Text = 'Channels to Use';
            app.ChannelstoUseListBox = uilistbox(app.UIFigure);
            app.ChannelstoUseListBox.Items = {'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4'};
            app.ChannelstoUseListBox.Multiselect = 'on';
            app.ChannelstoUseListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelstoUseListBoxValueChanged, true);
            app.ChannelstoUseListBox.Value = {'Channel 1'};
            app.MaininitialparametersPanel = uipanel(app.UIFigure);
            app.MaininitialparametersPanel.Title = 'Main initial parameters';
            app.AnchorChannelEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.AnchorChannelEditField_2Label.Text = 'AnchorChannel';
            app.AnchorChannelEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.AnchorChannelEditField.Value = 6;
            app.InputDirectoryEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.InputDirectoryEditFieldLabel.Text = 'InputDirectory';
            app.InputDirectoryEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.InputDirectoryEditField.Value = 'F:\Test-ISS-GUI';
            app.SliceprefixEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.SliceprefixEditField_2Label.Text = 'Slice prefix';
            app.SliceprefixEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.SliceprefixEditField.Value = 'MS18_Slice1_CV';
            app.AnchorRoundEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.AnchorRoundEditField_2Label.Text = 'AnchorRound';
            app.AnchorRoundEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.AnchorRoundEditField.Value = 8;
            app.GadChannelEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.GadChannelEditField_2Label.Text = 'GadChannel';
            app.GadChannelEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.GadChannelEditField.Value = 4;
            app.GadRoundEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.GadRoundEditField_2Label.Text = 'GadRound';
            app.GadRoundEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.GadRoundEditField.Value = 8;
            app.GcampChannelEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.GcampChannelEditField_2Label.Text = 'GcampChannel';
            app.GcampChannelEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.GcampChannelEditField.Value = 4;
            app.GcampRoundEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.GcampRoundEditField_2Label.Text = 'GcampRound';
            app.GcampRoundEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.GcampRoundEditField.Value = 8;
            app.InitialShiftChannelEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.InitialShiftChannelEditField_2Label.Text = 'InitialShiftChannel';
            app.InitialShiftChannelEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.InitialShiftChannelEditField.Value = 5;
            app.RawFileExtensionEditField_3Label = uilabel(app.MaininitialparametersPanel);
            app.RawFileExtensionEditField_3Label.Text = 'RawFileExtension';
            app.RawFileExtensionEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.RawFileExtensionEditField.Value = '.nd2';
            app.RoundsuffixEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.RoundsuffixEditField_2Label.Text = 'Round suffix';
            app.RoundsuffixEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.RoundsuffixEditField.Value = '_00,_01,_02,_03,_04,_05,_06,_anchor';
            app.NumberofcolorchannelsEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.NumberofcolorchannelsEditField_2Label.Text = 'Number of color channels';
            app.nBPEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.nBPEditField.ValueChangedFcn = createCallbackFcn(app, @nBPEditFieldValueChanged, true);
            app.nBPEditField.Value = 7;
            app.nRoundsEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.nRoundsEditFieldLabel.Text = 'nRounds';
            app.nRoundsEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.nRoundsEditField.ValueChangedFcn = createCallbackFcn(app, @nRoundsEditFieldValueChanged, true);
            app.nRoundsEditField.Value = 7;
            app.nExtraRoundsEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.nExtraRoundsEditFieldLabel.Text = 'nExtraRounds';
            app.nExtraRoundsEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.nExtraRoundsEditField.Value = 1;
            app.OutputDirectoryEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.OutputDirectoryEditFieldLabel.Text = 'OutputDirectory';
            app.OutputDirectoryEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.OutputDirectoryEditField.Value = 'F:\Test-ISS-GUI';
            app.ExtractandfilterButton_2 = uibutton(app.MaininitialparametersPanel, 'push');
            app.ExtractandfilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExtractandfilterButton_2Pushed, true);
            app.ExtractandfilterButton_2.Text = 'Extract and filter';
            app.RunAllButton_2 = uibutton(app.MaininitialparametersPanel, 'push');
            app.RunAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunAllButton_2Pushed, true);
            app.RunAllButton_2.Text = 'RunAll';
            app.ParametersthatcanbechangedafterextractandfilterLabel = uilabel(app.MaininitialparametersPanel);
            app.ParametersthatcanbechangedafterextractandfilterLabel.Text = 'Parameters that can be changed after extract and filter';
            app.IgnoreFirstChannelEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.IgnoreFirstChannelEditFieldLabel.Text = 'IgnoreFirstChannel';
            app.IgnoreFirstChannelEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.IgnoreFirstChannelEditField.Value = 1;
            app.TilestoUseListBoxLabel = uilabel(app.UIFigure);
            app.TilestoUseListBoxLabel.Text = 'Tiles to Use';
            app.TilestoUseListBox = uilistbox(app.UIFigure);
            app.TilestoUseListBox.Items = {'Tile 1', '', '', ''};
            app.TilestoUseListBox.Multiselect = 'on';
            app.TilestoUseListBox.ValueChangedFcn = createCallbackFcn(app, @TilestoUseListBoxValueChanged, true);
            app.TilestoUseListBox.Value = {'Tile 1'};
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.SteptoloadListBoxLabel = uilabel(app.UIFigure);
            app.SteptoloadListBoxLabel.Text = 'Step to load';
            app.SteptoloadListBox = uilistbox(app.UIFigure);
            app.SteptoloadListBox.Items = {'Extract', 'Register', 'Find_spots', 'Call_spots_OMP'};
            app.SteptoloadListBox.Value = 'Extract';
            app.VisualizeselectedtilesButton = uibutton(app.UIFigure, 'push');
            app.VisualizeselectedtilesButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeselectedtilesButtonPushed, true);
            app.VisualizeselectedtilesButton.Text = 'Visualize selected tiles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_ISS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end